classdef polya_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolyaFilterUIFigure          matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        NoiseTab                     matlab.ui.container.Tab
        TypeDropDownLabel            matlab.ui.control.Label
        TypeDropDown                 matlab.ui.control.DropDown
        SpeckleSigmaSliderLabel      matlab.ui.control.Label
        SpeckleSigmaSlider           matlab.ui.control.Slider
        ErrorSliderLabel             matlab.ui.control.Label
        ErrorSlider                  matlab.ui.control.Slider
        BurstSigmaSliderLabel        matlab.ui.control.Label
        BurstSigmaSlider             matlab.ui.control.Slider
        CorrelationLabel             matlab.ui.control.Label
        CorrelationSlider            matlab.ui.control.Slider
        TransitionSliderLabel        matlab.ui.control.Label
        TransitionSlider             matlab.ui.control.Slider
        SpeckleSigmaValue            matlab.ui.control.NumericEditField
        ErrorValue                   matlab.ui.control.NumericEditField
        TransitionValue              matlab.ui.control.NumericEditField
        BurstSigmaValue              matlab.ui.control.NumericEditField
        CorrelationValue             matlab.ui.control.NumericEditField
        GaussianSigmaLabel           matlab.ui.control.Label
        GaussianSigmaSlider          matlab.ui.control.Slider
        GaussianSigmaValue           matlab.ui.control.NumericEditField
        EdgeTab                      matlab.ui.container.Tab
        UseEdgeMapCheckBox           matlab.ui.control.CheckBox
        ThresholdSliderLabel         matlab.ui.control.Label
        EdgeMapThreshold             matlab.ui.control.Slider
        EdgeMapThresholdValue        matlab.ui.control.NumericEditField
        EdgeMapSigmaLabel            matlab.ui.control.Label
        EdgeMapSigma                 matlab.ui.control.Slider
        EdgeMapSigmaValue            matlab.ui.control.NumericEditField
        QuantizationTab              matlab.ui.container.Tab
        QuantizationCheckBox         matlab.ui.control.CheckBox
        QuantizationTypeButtonGroup  matlab.ui.container.ButtonGroup
        UniformButton                matlab.ui.control.RadioButton
        LloydMaxButton               matlab.ui.control.RadioButton
        ofpartitionsballtypesLabel   matlab.ui.control.Label
        BallTypesSlider              matlab.ui.control.Slider
        InverseQuantizationTypeButtonGroup  matlab.ui.container.ButtonGroup
        LowButton                    matlab.ui.control.RadioButton
        MeanButton                   matlab.ui.control.RadioButton
        HighButton                   matlab.ui.control.RadioButton
        BallTypesValue               matlab.ui.control.NumericEditField
        PolyaTab                     matlab.ui.container.Tab
        IterationsEditFieldLabel     matlab.ui.control.Label
        IterationsEditField          matlab.ui.control.NumericEditField
        StartingBallsSliderLabel     matlab.ui.control.Label
        StartingBallsSlider          matlab.ui.control.Slider
        BallstoAddEditFieldLabel     matlab.ui.control.Label
        BallstoAddEditField          matlab.ui.control.NumericEditField
        RadiusButtonGroup            matlab.ui.container.ButtonGroup
        Radius1                      matlab.ui.control.RadioButton
        Radius2                      matlab.ui.control.RadioButton
        RadiusSliderLabel            matlab.ui.control.Label
        RadiusSlider                 matlab.ui.control.Slider
        NormButtonGroup              matlab.ui.container.ButtonGroup
        Norm1                        matlab.ui.control.RadioButton
        Norm2                        matlab.ui.control.RadioButton
        NormInf                      matlab.ui.control.RadioButton
        StartingBallsValue           matlab.ui.control.NumericEditField
        RadiusValue                  matlab.ui.control.NumericEditField
        SelectImageButton            matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        ImagePathEditFieldLabel      matlab.ui.control.Label
        ImagePathEditField           matlab.ui.control.EditField
        LoadImageButton              matlab.ui.control.Button
        ApplyNoiseButton             matlab.ui.control.Button
        OriginalImage                matlab.ui.control.UIAxes
        NoisyImage                   matlab.ui.control.UIAxes
        EdgeImage                    matlab.ui.control.UIAxes
        PolyaImage                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        prefs; % prefs object for polya
        noise; % noise pref object for polya
        image; % input image
        edges; % edge map
        noisy_image; % noisy image
        output; % output image
    end
    
    methods (Access = private)
        function fillImageToFigure(app, name, impath)
            % https://www.mathworks.com/matlabcentral/answers/360670-imshow-in-app-designer-image-size-doesn-t-fit?requestedDomain=true
            % Remove title, axis labels, and tick labels
            title(name, []);
            xlabel(name, []);
            ylabel(name, []);
            name.XAxis.TickLabels = {};
            name.YAxis.TickLabels = {};
            
            I = imshow(impath, 'Parent', name, ...
                'XData', [1 name.Position(3)], ...
                'YData', [1 name.Position(4)]);
            
            % Set limits of axes
            name.XLim = [0 I.XData(2)];
            name.YLim = [0 I.YData(2)];
        end
        
        function toggleNoiseFields(app, noiseType)
            app.SpeckleSigmaSlider.Visible = 'off';
            app.SpeckleSigmaSliderLabel.Visible = 'off';
            app.SpeckleSigmaValue.Visible = 'off';
            app.ErrorSlider.Visible = 'off';
            app.ErrorSliderLabel.Visible = 'off';
            app.ErrorValue.Visible = 'off';
            app.BurstSigmaSlider.Visible = 'off';
            app.BurstSigmaSliderLabel.Visible = 'off';
            app.BurstSigmaValue.Visible = 'off';
            app.CorrelationSlider.Visible = 'off';
            app.CorrelationLabel.Visible = 'off';
            app.CorrelationValue.Visible = 'off';
            app.TransitionSlider.Visible = 'off';
            app.TransitionSliderLabel.Visible = 'off';
            app.TransitionValue.Visible = 'off';
            app.GaussianSigmaLabel.Visible = 'off';
            app.GaussianSigmaSlider.Visible = 'off';
            app.GaussianSigmaValue.Visible = 'off';
            
            switch(noiseType)
                case('none')
                case('speckle')
                    app.SpeckleSigmaSlider.Visible = 'on';
                    app.SpeckleSigmaSliderLabel.Visible = 'on';
                    app.SpeckleSigmaValue.Visible = 'on';
                case('binary-erasure')
                    app.TransitionSlider.Visible = 'on';
                    app.TransitionSliderLabel.Visible = 'on';
                    app.TransitionValue.Visible = 'on';
                    app.ErrorSlider.Visible = 'on';
                    app.ErrorSliderLabel.Visible = 'on';
                    app.ErrorValue.Visible = 'on';
                case('gaussian-markov')
                    app.GaussianSigmaLabel.Visible = 'on';
                    app.GaussianSigmaSlider.Visible = 'on';
                    app.GaussianSigmaValue.Visible = 'on';
                    app.CorrelationSlider.Visible = 'on';
                    app.CorrelationLabel.Visible = 'on';
                    app.CorrelationValue.Visible = 'on';
                case('speckle+gaussian-markov')
                    app.SpeckleSigmaSlider.Visible = 'on';
                    app.SpeckleSigmaSliderLabel.Visible = 'on';
                    app.SpeckleSigmaValue.Visible = 'on';
                    app.GaussianSigmaLabel.Visible = 'on';
                    app.GaussianSigmaSlider.Visible = 'on';
                    app.GaussianSigmaValue.Visible = 'on';
                    app.CorrelationSlider.Visible = 'on';
                    app.CorrelationLabel.Visible = 'on';
                    app.CorrelationValue.Visible = 'on';
                case('speckle+gaussian-burst')
                    app.SpeckleSigmaSlider.Visible = 'on';
                    app.SpeckleSigmaSliderLabel.Visible = 'on';
                    app.SpeckleSigmaValue.Visible = 'on';
                    app.TransitionSlider.Visible = 'on';
                    app.TransitionSliderLabel.Visible = 'on';
                    app.TransitionValue.Visible = 'on';
                    app.ErrorSlider.Visible = 'on';
                    app.ErrorSliderLabel.Visible = 'on';
                    app.ErrorValue.Visible = 'on';
                    app.BurstSigmaSlider.Visible = 'on';
                    app.BurstSigmaSliderLabel.Visible = 'on';
                    app.BurstSigmaValue.Visible = 'on';
                case('speckle+binary-erasure')
                    app.SpeckleSigmaSlider.Visible = 'on';
                    app.SpeckleSigmaSliderLabel.Visible = 'on';
                    app.SpeckleSigmaValue.Visible = 'on';
                    app.TransitionSlider.Visible = 'on';
                    app.TransitionSliderLabel.Visible = 'on';
                    app.TransitionValue.Visible = 'on';
                    app.ErrorSlider.Visible = 'on';
                    app.ErrorSliderLabel.Visible = 'on';
                    app.ErrorValue.Visible = 'on';
            end
        end
        
        function setNoiseValues(app, noiseType)
            switch(noiseType)
                case('none')
                case('speckle')
                case('binary-erasure')
                case('gaussian-markov')
                case('speckle+gaussian-markov')
                case('speckle+gaussian-burst')
                case('speckle+binary-erasure')
            end
        end
        
        %         Function to map noise type dropdown values to add_noise.m input noise types
        function noiseType = setNoiseType(app, value)
            switch(value)
                case('none')
                    noiseType = {'none'};
                case('speckle')
                    noiseType = {'speckle'};
                case('binary-erasure')
                    noiseType = {'binary-erasure'};
                case('gaussian-markov')
                    noiseType = {'gauss-markov'};
                case('speckle+gaussian-markov')
                    noiseType = {'speckle', 'gauss-markov'};
                case('speckle+gaussian-burst')
                    noiseType = {'speckle', 'gaussian-burst'};
                case('speckle+binary-erasure')
                    noiseType = {'speckle', 'binary-erasure'};
            end
        end
        
        function applyEdgeMap(app)
            if app.UseEdgeMapCheckBox.Value
                temp_edge_gray = app.noisy_image;
                % If it's a colour image, convert it to grayscale
                if size(app.noisy_image, 3) > 1
                    temp_edge_gray = rgb2gray(app.noisy_image);
                end
                app.edges = edge(temp_edge_gray, ...
                    app.prefs.edges.filter, ...
                    app.prefs.edges.thresh, ...
                    app.prefs.edges.sigma);
                fillImageToFigure(app, app.EdgeImage, app.edges);
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            %             Hide all plots where images will go. Only display when there is an image.
            app.OriginalImage.Visible = 'off';
            app.NoisyImage.Visible = 'off';
            app.EdgeImage.Visible = 'off';
            app.PolyaImage.Visible = 'off';
            
            %             Hide all noise options until one is selected.
            toggleNoiseFields(app, 'none');
            
            rng(0, 'twister');
            
            %             Initialize Edge Map Values
            app.prefs.edges.use_edges = app.UseEdgeMapCheckBox.Value;
            app.prefs.edges.filter = 'canny';
            % The variance of Canny's gaussian filter. Default is sqrt(2)
            app.prefs.edges.sigma = app.EdgeMapSigma.Value;
            % Threshold for the Canny method
            % Oil Spill: 0.4
            % Lena: with gauss = 0.2, with burst = 0.38
            % Two ships: 0.5
            app.prefs.edges.thresh = app.EdgeMapThreshold.Value;
            
            %             Initialize Noise Values
            % Noise parameters
            % BW Gaussian Noise Parameters
            app.noise.bw.gaussian_std_dev = 1;
            app.noise.bw.gaussian_mean = 0;
            app.noise.bw.gaussian_confidence_interval = 0.8; % Error rate is 1 - this
            
            % Colour & Greyscale Noise Parameters
            app.noise.speckle.distribution = 'rayleigh';
            app.noise.speckle.sigma = 0;
            
            app.noise.gaussian.sigma = app.SpeckleSigmaSlider.Value;
            app.noise.gaussian.mean = 0;
            
            app.noise.bursty.type = 'binary'; % 'gaussian' or 'binary'
            app.noise.bursty.transition_prob = app.TransitionSlider.Value;
            app.noise.bursty.error = app.ErrorSlider.Value; % 0.2 for gaussian, 0.1 for binary
            app.noise.bursty.mean = 0;
            app.noise.bursty.sigma = app.BurstSigmaSlider.Value;
            
            app.noise.gauss_markov.correlation = 0.9; % (-1, 1)
            app.noise.gauss_markov.mean = 0;
            app.noise.gauss_markov.sigma = 10;
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            file = uigetfile({'*.jpg;*.tif;*.png;*.gif;*.tiff;*.bmp','All Image Files'; ...
                '*.*','All Files' }, '../images/');
            app.PolyaFilterUIFigure.Visible = 'off';
            app.PolyaFilterUIFigure.Visible = 'on';
            app.ImagePathEditField.Value = ['../images/' file];
            
            LoadImageButtonPushed(app, event);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            path = app.ImagePathEditField.Value;
            if strcmp(path, "")
                return;
            end
            app.image = imread(path);
            fillImageToFigure(app, app.OriginalImage, path);
            
            imagepath = app.ImagePathEditField.Value;
            im_info = imfinfo(imagepath);
            switch(im_info.ColorType)
                case('truecolor')
                    app.prefs.image.type = 'rgb';
                case('grayscale')
                    app.prefs.image.type = 'gray';
                case('indexed')
                    app.prefs.image.type = 'bw';
                    app.prefs.image.type = 'gray';
                otherwise
                    disp("Can't detect image type. Need to override prefs.image.type");
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.QuantizationCheckBox == 0
                app.prefs.quant.num_ball_types = 256; % [2 - 256]
                app.prefs.quant.type = 'unif'; % unif, lloyd
                app.prefs.quant.inverse = 'mid'; % low, high, mid
            end
            
            if strcmp(app.prefs.image.type, 'rgb') || strcmp(app.prefs.image.type, 'ycbcr')
                if strcmp(app.prefs.image.type, 'ycbcr')
                    app.noisy_image = rgb2ycbcr(app.noisy_image);
                end
                % Save each colour channel for polya and median filter
                channel_1 = app.noisy_image(:, :, 1);
                channel_2 = app.noisy_image(:, :, 2);
                channel_3 = app.noisy_image(:, :, 3);
                
                % Run the greyscale polya filter on each channel individually
                channel_1_p = polyafilt(channel_1, app.prefs);
                channel_2_p = polyafilt(channel_2, app.prefs);
                channel_3_p = polyafilt(channel_3, app.prefs);
                
                % Reconstruct the colour image
                app.output = cat(3, channel_1_p, channel_2_p, channel_3_p);
                
                if strcmp(app.prefs.image.type, 'ycbcr')
                    app.output = ycbcr2rgb(app.output);
                    app.noisy_image = ycbcr2rgb(app.noisy_image);
                end
                
            elseif strcmp(app.prefs.image.type, 'gray')
                % Run the grayscale polya filter
                app.output = polyafilt(app.noisy_image, app.prefs);
                
            elseif strcmp(app.prefs.image.type, 'bw')
                app.prefs.quant.num_ball_types = 3;
                
                % Run the black and white polya filter
                app.output = polyafilt(app.noisy_image, app.prefs);
                
                % Convert to numeric type for metric functions
                app.output = uint8(app.output);
                app.image = uint8(app.image);
            end
            
            fillImageToFigure(app, app.PolyaImage, app.output);
        end

        % Selection changed function: QuantizationTypeButtonGroup
        function QuantizationTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.QuantizationTypeButtonGroup.SelectedObject;
            if strcmp(selectedButton, 'Uniform')
                app.prefs.quant.type = 'unif';
            else
                app.prefs.quant.type = 'lloyd';
            end
        end

        % Selection changed function: 
        % InverseQuantizationTypeButtonGroup
        function InverseQuantizationTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InverseQuantizationTypeButtonGroup.SelectedObject;
            if strcmp(selectedButton, 'Low')
                app.prefs.quant.inverse = 'low';
            elseif strcmp(selectedButton, 'High')
                app.prefs.quant.inverse = 'high';
            else
                app.prefs.quant.inverse = 'mid';
            end
        end

        % Selection changed function: NormButtonGroup
        function NormButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormButtonGroup.SelectedObject;
            if strcmp(selectedButton, '1')
                app.prefs.adj.norm = 1;
            elseif strcmp(selectedButton, '2')
                app.prefs.adj.norm = 2;
            else
                app.prefs.adj.norm = 'inf';
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            toggleNoiseFields(app, value);
            app.noise.type = setNoiseType(app, value);
        end

        % Value changing function: SpeckleSigmaSlider
        function SpeckleSigmaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SpeckleSigmaValue.Value = changingValue;
            app.noise.speckle.sigma = changingValue;
        end

        % Value changing function: ErrorSlider
        function ErrorSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ErrorValue.Value = changingValue;
            app.noise.bursty.error = changingValue;
        end

        % Value changing function: TransitionSlider
        function TransitionSliderValueChanging(app, event)
            changingValue = event.Value;
            app.TransitionValue.Value = changingValue;
            app.noise.bursty.transition_prob = changingValue;
        end

        % Value changing function: BurstSigmaSlider
        function BurstSigmaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.BurstSigmaValue.Value = changingValue;
            app.noise.bursty.sigma = changingValue;
        end

        % Value changing function: CorrelationSlider
        function CorrelationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CorrelationValue.Value = changingValue;
            app.noise.gauss_markov.correlation = changingValue;
        end

        % Value changing function: GaussianSigmaSlider
        function GaussianSigmaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.GaussianSigmaValue.Value = changingValue;
            app.noise.gauss_markov.sigma = changingValue;
        end

        % Button pushed function: ApplyNoiseButton
        function ApplyNoiseButtonPushed(app, event)
            app.noisy_image = add_noise(app.image, app.prefs, app.noise);
            fillImageToFigure(app, app.NoisyImage, app.noisy_image);
        end

        % Value changed function: EdgeMapThreshold
        function EdgeMapThresholdValueChanged(app, event)
            value = app.EdgeMapThreshold.Value;
            app.prefs.edges.thresh = value;
            
            applyEdgeMap(app);
        end

        % Value changed function: SpeckleSigmaSlider
        function SpeckleSigmaSliderValueChanged(app, event)
            value = app.SpeckleSigmaSlider.Value;
            ApplyNoiseButtonPushed(app, event);
        end

        % Value changed function: ErrorSlider
        function ErrorSliderValueChanged(app, event)
            value = app.ErrorSlider.Value;
            ApplyNoiseButtonPushed(app, event);
        end

        % Value changed function: BurstSigmaSlider
        function BurstSigmaSliderValueChanged(app, event)
            value = app.BurstSigmaSlider.Value;
            ApplyNoiseButtonPushed(app, event);
        end

        % Value changed function: CorrelationSlider
        function CorrelationSliderValueChanged(app, event)
            value = app.CorrelationSlider.Value;
            ApplyNoiseButtonPushed(app, event);
        end

        % Value changed function: TransitionSlider
        function TransitionSliderValueChanged(app, event)
            value = app.TransitionSlider.Value;
            ApplyNoiseButtonPushed(app, event);
        end

        % Value changed function: GaussianSigmaSlider
        function GaussianSigmaSliderValueChanged(app, event)
            value = app.GaussianSigmaSlider.Value;
            ApplyNoiseButtonPushed(app, event);
        end

        % Value changing function: EdgeMapThreshold
        function EdgeMapThresholdValueChanging(app, event)
            changingValue = event.Value;
            app.EdgeMapThresholdValue.Value = changingValue;
        end

        % Value changed function: EdgeMapSigma
        function EdgeMapSigmaValueChanged(app, event)
            value = app.EdgeMapSigma.Value;
            app.prefs.edges.sigma = value;
            
            applyEdgeMap(app);
        end

        % Value changing function: EdgeMapSigma
        function EdgeMapSigmaValueChanging(app, event)
            changingValue = event.Value;
            app.EdgeMapSigmaValue.Value = changingValue;
        end

        % Value changing function: BallTypesSlider
        function BallTypesSliderValueChanging(app, event)
            changingValue = floor(event.Value);
            app.BallTypesValue.Value = changingValue;
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.prefs.polya.iterations = value;
        end

        % Value changing function: StartingBallsSlider
        function StartingBallsSliderValueChanging(app, event)
            changingValue = floor(event.Value);
            app.StartingBallsValue.Value = changingValue;
        end

        % Value changed function: StartingBallsSlider
        function StartingBallsSliderValueChanged(app, event)
            value = floor(app.StartingBallsSlider.Value);
            app.StartingBallsSlider.Value = value;
            
            app.prefs.polya.starting_balls = value;
        end

        % Value changed function: BallstoAddEditField
        function BallstoAddEditFieldValueChanged(app, event)
            value = app.BallstoAddEditField.Value;
            app.prefs.polya.balls_to_add = value;
        end

        % Selection changed function: RadiusButtonGroup
        function RadiusButtonGroupSelectionChanged(app, event)
            selectedButton = app.RadiusButtonGroup.SelectedObject;
            if strcmp(selectedButton, '1')
                app.prefs.adj.radius = 1;
            else
                strcmp(selectedButton, '2')
                app.prefs.adj.radius = 2;
            end
        end

        % Value changed function: RadiusSlider
        function RadiusSliderValueChanged(app, event)
            value = floor(app.RadiusSlider.Value);
            app.RadiusSlider.Value = value;
            
            app.prefs.adj.radius = value;
        end

        % Value changing function: RadiusSlider
        function RadiusSliderValueChanging(app, event)
            changingValue = floor(event.Value);
            app.RadiusValue.Value = changingValue;
        end

        % Value changed function: BallTypesSlider
        function BallTypesSliderValueChanged(app, event)
            value = floor(app.BallTypesSlider.Value);
            app.prefs.quant.num_ball_types = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PolyaFilterUIFigure = uifigure;
            app.PolyaFilterUIFigure.Name = 'Polya Filter';
            app.TabGroup = uitabgroup(app.PolyaFilterUIFigure);
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.TypeDropDownLabel = uilabel(app.NoiseTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.NoiseTab);
            app.TypeDropDown.Items = {'None', 'Speckle', 'Binary Erasure', 'Gaussian Markov', 'Speckle + Gaussian Markov', 'Speckle + Gaussian Burst (Hidden Markov)', 'Speckle + Binary Erasure (Hidden Markov)'};
            app.TypeDropDown.ItemsData = {'none', 'speckle', 'binary-erasure', 'gaussian-markov', 'speckle+gaussian-markov', 'speckle+gaussian-burst', 'speckle+binary-erasure'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'none';
            app.SpeckleSigmaSliderLabel = uilabel(app.NoiseTab);
            app.SpeckleSigmaSliderLabel.Text = 'Speckle Sigma';
            app.SpeckleSigmaSlider = uislider(app.NoiseTab);
            app.SpeckleSigmaSlider.Limits = [0 0.275];
            app.SpeckleSigmaSlider.ValueChangedFcn = createCallbackFcn(app, @SpeckleSigmaSliderValueChanged, true);
            app.SpeckleSigmaSlider.ValueChangingFcn = createCallbackFcn(app, @SpeckleSigmaSliderValueChanging, true);
            app.SpeckleSigmaSlider.Value = 0.01;
            app.ErrorSliderLabel = uilabel(app.NoiseTab);
            app.ErrorSliderLabel.Text = 'Error';
            app.ErrorSlider = uislider(app.NoiseTab);
            app.ErrorSlider.Limits = [0 1];
            app.ErrorSlider.ValueChangedFcn = createCallbackFcn(app, @ErrorSliderValueChanged, true);
            app.ErrorSlider.ValueChangingFcn = createCallbackFcn(app, @ErrorSliderValueChanging, true);
            app.ErrorSlider.Value = 0.8;
            app.BurstSigmaSliderLabel = uilabel(app.NoiseTab);
            app.BurstSigmaSliderLabel.Text = 'Burst Sigma';
            app.BurstSigmaSlider = uislider(app.NoiseTab);
            app.BurstSigmaSlider.Limits = [0 1];
            app.BurstSigmaSlider.ValueChangedFcn = createCallbackFcn(app, @BurstSigmaSliderValueChanged, true);
            app.BurstSigmaSlider.ValueChangingFcn = createCallbackFcn(app, @BurstSigmaSliderValueChanging, true);
            app.CorrelationLabel = uilabel(app.NoiseTab);
            app.CorrelationLabel.Text = 'Correlation';
            app.CorrelationSlider = uislider(app.NoiseTab);
            app.CorrelationSlider.Limits = [-1 1];
            app.CorrelationSlider.ValueChangedFcn = createCallbackFcn(app, @CorrelationSliderValueChanged, true);
            app.CorrelationSlider.ValueChangingFcn = createCallbackFcn(app, @CorrelationSliderValueChanging, true);
            app.TransitionSliderLabel = uilabel(app.NoiseTab);
            app.TransitionSliderLabel.Text = 'Transition';
            app.TransitionSlider = uislider(app.NoiseTab);
            app.TransitionSlider.Limits = [0 1];
            app.TransitionSlider.ValueChangedFcn = createCallbackFcn(app, @TransitionSliderValueChanged, true);
            app.TransitionSlider.ValueChangingFcn = createCallbackFcn(app, @TransitionSliderValueChanging, true);
            app.SpeckleSigmaValue = uieditfield(app.NoiseTab, 'numeric');
            app.SpeckleSigmaValue.Editable = 'off';
            app.SpeckleSigmaValue.Enable = 'off';
            app.ErrorValue = uieditfield(app.NoiseTab, 'numeric');
            app.ErrorValue.Editable = 'off';
            app.ErrorValue.Enable = 'off';
            app.TransitionValue = uieditfield(app.NoiseTab, 'numeric');
            app.TransitionValue.Editable = 'off';
            app.TransitionValue.Enable = 'off';
            app.BurstSigmaValue = uieditfield(app.NoiseTab, 'numeric');
            app.BurstSigmaValue.Editable = 'off';
            app.BurstSigmaValue.Enable = 'off';
            app.CorrelationValue = uieditfield(app.NoiseTab, 'numeric');
            app.CorrelationValue.Editable = 'off';
            app.CorrelationValue.Enable = 'off';
            app.GaussianSigmaLabel = uilabel(app.NoiseTab);
            app.GaussianSigmaLabel.Text = 'Gaussian Sigma';
            app.GaussianSigmaSlider = uislider(app.NoiseTab);
            app.GaussianSigmaSlider.Limits = [0 50];
            app.GaussianSigmaSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianSigmaSliderValueChanged, true);
            app.GaussianSigmaSlider.ValueChangingFcn = createCallbackFcn(app, @GaussianSigmaSliderValueChanging, true);
            app.GaussianSigmaValue = uieditfield(app.NoiseTab, 'numeric');
            app.GaussianSigmaValue.Editable = 'off';
            app.GaussianSigmaValue.Enable = 'off';
            app.EdgeTab = uitab(app.TabGroup);
            app.EdgeTab.Title = 'Edge';
            app.UseEdgeMapCheckBox = uicheckbox(app.EdgeTab);
            app.UseEdgeMapCheckBox.Text = 'Use Edge Map';
            app.ThresholdSliderLabel = uilabel(app.EdgeTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.EdgeMapThreshold = uislider(app.EdgeTab);
            app.EdgeMapThreshold.Limits = [0 1];
            app.EdgeMapThreshold.ValueChangedFcn = createCallbackFcn(app, @EdgeMapThresholdValueChanged, true);
            app.EdgeMapThreshold.ValueChangingFcn = createCallbackFcn(app, @EdgeMapThresholdValueChanging, true);
            app.EdgeMapThresholdValue = uieditfield(app.EdgeTab, 'numeric');
            app.EdgeMapThresholdValue.Editable = 'off';
            app.EdgeMapThresholdValue.Enable = 'off';
            app.EdgeMapSigmaLabel = uilabel(app.EdgeTab);
            app.EdgeMapSigmaLabel.Text = 'Sigma';
            app.EdgeMapSigma = uislider(app.EdgeTab);
            app.EdgeMapSigma.Limits = [0 10];
            app.EdgeMapSigma.ValueChangedFcn = createCallbackFcn(app, @EdgeMapSigmaValueChanged, true);
            app.EdgeMapSigma.ValueChangingFcn = createCallbackFcn(app, @EdgeMapSigmaValueChanging, true);
            app.EdgeMapSigmaValue = uieditfield(app.EdgeTab, 'numeric');
            app.EdgeMapSigmaValue.Editable = 'off';
            app.EdgeMapSigmaValue.Enable = 'off';
            app.QuantizationTab = uitab(app.TabGroup);
            app.QuantizationTab.Title = 'Quantization';
            app.QuantizationCheckBox = uicheckbox(app.QuantizationTab);
            app.QuantizationCheckBox.Text = 'Quantization';
            app.QuantizationTypeButtonGroup = uibuttongroup(app.QuantizationTab);
            app.QuantizationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @QuantizationTypeButtonGroupSelectionChanged, true);
            app.QuantizationTypeButtonGroup.Title = 'Quantization Type';
            app.UniformButton = uiradiobutton(app.QuantizationTypeButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.UniformButton.Value = true;
            app.LloydMaxButton = uiradiobutton(app.QuantizationTypeButtonGroup);
            app.LloydMaxButton.Text = 'Lloyd-Max';
            app.ofpartitionsballtypesLabel = uilabel(app.QuantizationTab);
            app.ofpartitionsballtypesLabel.Text = {'# of partitions '; '/ ball types'};
            app.BallTypesSlider = uislider(app.QuantizationTab);
            app.BallTypesSlider.Limits = [2 256];
            app.BallTypesSlider.ValueChangedFcn = createCallbackFcn(app, @BallTypesSliderValueChanged, true);
            app.BallTypesSlider.ValueChangingFcn = createCallbackFcn(app, @BallTypesSliderValueChanging, true);
            app.BallTypesSlider.Value = 20;
            app.InverseQuantizationTypeButtonGroup = uibuttongroup(app.QuantizationTab);
            app.InverseQuantizationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InverseQuantizationTypeButtonGroupSelectionChanged, true);
            app.InverseQuantizationTypeButtonGroup.Title = 'Inverse Quantization Type';
            app.LowButton = uiradiobutton(app.InverseQuantizationTypeButtonGroup);
            app.LowButton.Text = 'Low';
            app.LowButton.Value = true;
            app.MeanButton = uiradiobutton(app.InverseQuantizationTypeButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.HighButton = uiradiobutton(app.InverseQuantizationTypeButtonGroup);
            app.HighButton.Text = 'High';
            app.BallTypesValue = uieditfield(app.QuantizationTab, 'numeric');
            app.BallTypesValue.Limits = [2 256];
            app.BallTypesValue.Value = 20;
            app.PolyaTab = uitab(app.TabGroup);
            app.PolyaTab.Title = 'Polya';
            app.IterationsEditFieldLabel = uilabel(app.PolyaTab);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.PolyaTab, 'numeric');
            app.IterationsEditField.Limits = [0 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.StartingBallsSliderLabel = uilabel(app.PolyaTab);
            app.StartingBallsSliderLabel.Text = 'Starting Balls';
            app.StartingBallsSlider = uislider(app.PolyaTab);
            app.StartingBallsSlider.Limits = [100 1000];
            app.StartingBallsSlider.ValueChangedFcn = createCallbackFcn(app, @StartingBallsSliderValueChanged, true);
            app.StartingBallsSlider.ValueChangingFcn = createCallbackFcn(app, @StartingBallsSliderValueChanging, true);
            app.StartingBallsSlider.Value = 100;
            app.BallstoAddEditFieldLabel = uilabel(app.PolyaTab);
            app.BallstoAddEditFieldLabel.Text = 'Balls to Add';
            app.BallstoAddEditField = uieditfield(app.PolyaTab, 'numeric');
            app.BallstoAddEditField.Limits = [0 Inf];
            app.BallstoAddEditField.RoundFractionalValues = 'on';
            app.BallstoAddEditField.ValueDisplayFormat = '%.0f';
            app.BallstoAddEditField.ValueChangedFcn = createCallbackFcn(app, @BallstoAddEditFieldValueChanged, true);
            app.BallstoAddEditField.Value = 20;
            app.RadiusButtonGroup = uibuttongroup(app.PolyaTab);
            app.RadiusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RadiusButtonGroupSelectionChanged, true);
            app.RadiusButtonGroup.Title = 'Radius';
            app.Radius1 = uiradiobutton(app.RadiusButtonGroup);
            app.Radius1.Text = '1';
            app.Radius1.Value = true;
            app.Radius2 = uiradiobutton(app.RadiusButtonGroup);
            app.Radius2.Text = '2';
            app.RadiusSliderLabel = uilabel(app.PolyaTab);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.PolyaTab);
            app.RadiusSlider.Limits = [1 10];
            app.RadiusSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusSliderValueChanged, true);
            app.RadiusSlider.ValueChangingFcn = createCallbackFcn(app, @RadiusSliderValueChanging, true);
            app.RadiusSlider.Value = 1;
            app.NormButtonGroup = uibuttongroup(app.PolyaTab);
            app.NormButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormButtonGroupSelectionChanged, true);
            app.NormButtonGroup.Title = 'Norm';
            app.Norm1 = uiradiobutton(app.NormButtonGroup);
            app.Norm1.Text = '1';
            app.Norm1.Value = true;
            app.Norm2 = uiradiobutton(app.NormButtonGroup);
            app.Norm2.Text = '2';
            app.NormInf = uiradiobutton(app.NormButtonGroup);
            app.NormInf.Text = 'Inf';
            app.StartingBallsValue = uieditfield(app.PolyaTab, 'numeric');
            app.StartingBallsValue.Limits = [100 1000];
            app.StartingBallsValue.Value = 100;
            app.RadiusValue = uieditfield(app.PolyaTab, 'numeric');
            app.RadiusValue.Limits = [1 10];
            app.RadiusValue.Value = 1;
            app.SelectImageButton = uibutton(app.PolyaFilterUIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.RunButton = uibutton(app.PolyaFilterUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ImagePathEditFieldLabel = uilabel(app.PolyaFilterUIFigure);
            app.ImagePathEditFieldLabel.Text = 'Image Path';
            app.ImagePathEditField = uieditfield(app.PolyaFilterUIFigure, 'text');
            app.ImagePathEditField.Editable = 'off';
            app.LoadImageButton = uibutton(app.PolyaFilterUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ApplyNoiseButton = uibutton(app.PolyaFilterUIFigure, 'push');
            app.ApplyNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyNoiseButtonPushed, true);
            app.ApplyNoiseButton.Text = 'Apply Noise';
            app.OriginalImage = uiaxes(app.PolyaFilterUIFigure);
            title(app.OriginalImage, 'Title')
            xlabel(app.OriginalImage, 'X')
            ylabel(app.OriginalImage, 'Y')
            app.NoisyImage = uiaxes(app.PolyaFilterUIFigure);
            title(app.NoisyImage, 'Title')
            xlabel(app.NoisyImage, 'X')
            ylabel(app.NoisyImage, 'Y')
            app.EdgeImage = uiaxes(app.PolyaFilterUIFigure);
            title(app.EdgeImage, 'Title')
            xlabel(app.EdgeImage, 'X')
            ylabel(app.EdgeImage, 'Y')
            app.PolyaImage = uiaxes(app.PolyaFilterUIFigure);
            title(app.PolyaImage, 'Title')
            xlabel(app.PolyaImage, 'X')
            ylabel(app.PolyaImage, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = polya_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolyaFilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolyaFilterUIFigure)
        end
    end
end